classdef test_formant_synth_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        G3Knob       matlab.ui.control.Knob
        G3KnobLabel  matlab.ui.control.Label
        B3Knob       matlab.ui.control.Knob
        B3KnobLabel  matlab.ui.control.Label
        F3Knob       matlab.ui.control.Knob
        F3KnobLabel  matlab.ui.control.Label
        G2Knob       matlab.ui.control.Knob
        G2KnobLabel  matlab.ui.control.Label
        G1Knob       matlab.ui.control.Knob
        G1KnobLabel  matlab.ui.control.Label
        B2Knob       matlab.ui.control.Knob
        B2KnobLabel  matlab.ui.control.Label
        B1Knob       matlab.ui.control.Knob
        B1KnobLabel  matlab.ui.control.Label
        F2Knob       matlab.ui.control.Knob
        F2KnobLabel  matlab.ui.control.Label
        F1Knob       matlab.ui.control.Knob
        F1KnobLabel  matlab.ui.control.Label
        UButton      matlab.ui.control.Button
        OButton      matlab.ui.control.Button
        IButton      matlab.ui.control.Button
        EButton      matlab.ui.control.Button
        AButton      matlab.ui.control.Button
    end

    
    properties (Access = private)

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        [x_a, fs] = audioread('samples/Mixdown/52-a-.wav');
        x_e = audioread('samples/Mixdown/53-e-.wav');
        x_i = audioread('samples/Mixdown/54-i-.wav');
        x_o = audioread('samples/Mixdown/55-o-.wav');
        x_u = audioread('samples/Mixdown/56-u-.wav');
        
        x_b = audioread('samples/Mixdown/3- b.wav');
        x_c = audioread('samples/Mixdown/4-c.wav');
        x_cc = audioread('samples/Mixdown/5-č.wav');
        x_d = audioread('samples/Mixdown/6-d.wav');
        % x_dd = audioread('samples/Mixdown/7-ď.wav');
        % x_f = audioread('samples/Mixdown/11-f.wav');
        % x_g = audioread('samples/Mixdown/12- g.wav');
        % x_h = audioread('samples/Mixdown/13-h.wav');
        % x_j = audioread('samples/Mixdown/17-j.wav');
        % x_k = audioread('samples/Mixdown/18-k.wav');
        % x_l = audioread('samples/Mixdown/20-l.wav');
        % x_m = audioread('samples/Mixdown/21-m.wav');
        % x_n = audioread('samples/Mixdown/22-n.wav');
        % x_nn = audioread('samples/Mixdown/23-ň.wav');
        % x_p = audioread('samples/Mixdown/26-p.wav');
        % x_q = audioread('samples/Mixdown/28-qk.wav');
        % x_r = audioread('samples/Mixdown/31-r.wav');
        % x_rr = audioread('samples/Mixdown/32-ř.wav');
        % x_s = audioread('samples/Mixdown/33-s.wav');
        % x_ss = audioread('samples/Mixdown/35-š.wav');
        % x_t = audioread('samples/Mixdown/36-t.wav');
        % x_tt = audioread('samples/Mixdown/37-ť.wav');
        % x_v = audioread('samples/Mixdown/41-v.wav');
        % x_w = audioread('samples/Mixdown/42-w.wav');
        % x_x = audioread('samples/Mixdown/44-ks.wav');
        % x_y = audioread('samples/Mixdown/46-y.wav');
        % x_z = audioread('samples/Mixdown/49-z.wav');
        % x_zz = audioread('samples/Mixdown/51-ž.wav');  

        %% convert to mono and create array
        X = {x_a, x_e, x_i, x_o, x_u, x_b, x_c, x_cc, x_d};

        %% Pre processing
        for j=1:length(X)
            x = X{j};
            if size(x, 2) % convert audio to mono
                x = x(:, 1);
            end
            x = x./max(abs(x));         % normalize between -1 and 1
            x = x.*hann(length(x));   % multiply with window
            x = preemfaze(x);           % preemphase lowpass filtering
            X{j}= x;
        end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AButton = uibutton(app.UIFigure, 'push');
            app.AButton.Text = 'A';
            app.EButton = uibutton(app.UIFigure, 'push');
            app.EButton.Text = 'E';
            app.IButton = uibutton(app.UIFigure, 'push');
            app.IButton.Text = 'I';
            app.OButton = uibutton(app.UIFigure, 'push');
            app.OButton.Text = 'O';
            app.UButton = uibutton(app.UIFigure, 'push');
            app.UButton.Text = 'U';
            app.F1KnobLabel = uilabel(app.UIFigure);
            app.F1KnobLabel.Text = 'F1';
            app.F1Knob = uiknob(app.UIFigure, 'continuous');
            app.F2KnobLabel = uilabel(app.UIFigure);
            app.F2KnobLabel.Text = 'F2';
            app.F2Knob = uiknob(app.UIFigure, 'continuous');
            app.B1KnobLabel = uilabel(app.UIFigure);
            app.B1KnobLabel.Text = 'B1';
            app.B1Knob = uiknob(app.UIFigure, 'continuous');
            app.B2KnobLabel = uilabel(app.UIFigure);
            app.B2KnobLabel.Text = 'B2';
            app.B2Knob = uiknob(app.UIFigure, 'continuous');
            app.G1KnobLabel = uilabel(app.UIFigure);
            app.G1KnobLabel.Text = 'G1';
            app.G1Knob = uiknob(app.UIFigure, 'continuous');
            app.G2KnobLabel = uilabel(app.UIFigure);
            app.G2KnobLabel.Text = 'G2';
            app.G2Knob = uiknob(app.UIFigure, 'continuous');
            app.F3KnobLabel = uilabel(app.UIFigure);
            app.F3KnobLabel.Text = 'F3';
            app.F3Knob = uiknob(app.UIFigure, 'continuous');
            app.B3KnobLabel = uilabel(app.UIFigure);
            app.B3KnobLabel.Text = 'B3';
            app.B3Knob = uiknob(app.UIFigure, 'continuous');
            app.G3KnobLabel = uilabel(app.UIFigure);
            app.G3KnobLabel.Text = 'G3';
            app.G3Knob = uiknob(app.UIFigure, 'continuous');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = test_formant_synth_1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end